classdef Interfaz < matlab.apps.AppBase
    %INTERFAZ app.ContraseaEditField.Password = true;
    %   app.ContraseaEditField.Password = true;

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        INICIOTab                       matlab.ui.container.Tab
        ContraseaEditField              matlab.ui.control.EditField
        ContraseaEditFieldLabel         matlab.ui.control.Label
        UsuarioEditField                matlab.ui.control.EditField
        UsuarioEditFieldLabel           matlab.ui.control.Label
        IniciarsesinButton              matlab.ui.control.Button
        Image8                          matlab.ui.control.Image
        OPCIONESTab                     matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Button                          matlab.ui.control.Button
        InformacinadicionalButton       matlab.ui.control.Button
        APPdiagnsticoButton             matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        DIAGNSTICOTab                   matlab.ui.container.Tab
        VisualizarcaractersticasButton  matlab.ui.control.Button
        Label_9                         matlab.ui.control.Label
        DIAGNSTICOLabel                 matlab.ui.control.Label
        IMAGENDECOPAYDISCOLabel         matlab.ui.control.Label
        SegmentacinydiagnsticoButton    matlab.ui.control.Button
        SubirImagenButton               matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        IMAGENDELARETINALabel           matlab.ui.control.Label
        Image11                         matlab.ui.control.Image
        Label_8                         matlab.ui.control.Label
        ComprobarcalidadButton          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Image12                         matlab.ui.control.Image
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        CARACTERSTICASTab               matlab.ui.container.Tab
        LimpiarcamposButton             matlab.ui.control.Button
        CDREditField                    matlab.ui.control.NumericEditField
        EntropiaEditField_3Label_2      matlab.ui.control.Label
        SEGMENTACINLabel                matlab.ui.control.Label
        CALIDADLabel                    matlab.ui.control.Label
        PSNREditField_2                 matlab.ui.control.NumericEditField
        PSNREditField_3Label            matlab.ui.control.Label
        ECMEditField_2                  matlab.ui.control.NumericEditField
        ECMEditField_3Label             matlab.ui.control.Label
        VarianzaEditField_2             matlab.ui.control.NumericEditField
        VarianzaEditField_3Label        matlab.ui.control.Label
        RangodinmicoEditField_2         matlab.ui.control.NumericEditField
        RangodinmicoEditField_3Label    matlab.ui.control.Label
        EntropiaEditField_2             matlab.ui.control.NumericEditField
        EntropiaEditField_3Label        matlab.ui.control.Label
        CARACTERSTICASLabel_2           matlab.ui.control.Label
        Image11_2                       matlab.ui.control.Image
        Button_7                        matlab.ui.control.Button
        Image12_2                       matlab.ui.control.Image
        INFOTab                         matlab.ui.container.Tab
        ProductoButton                  matlab.ui.control.Button
        ContctanosButton                matlab.ui.control.Button
        QuinessomosButton               matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Image3_2                        matlab.ui.control.Image
        PRODUCTOTab                     matlab.ui.container.Tab
        SoftwaredeApoyoalDiagnsticodeGlaucomaLabel  matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Image10                         matlab.ui.control.Image
        Button_4                        matlab.ui.control.Button
        Image9                          matlab.ui.control.Image
        QUIENESSOMOSTab                 matlab.ui.container.Tab
        InnovacincalidadaccesibilidadycompromisoconlasaludLabel  matlab.ui.control.Label
        VALORESLabel                    matlab.ui.control.Label
        MISIONYVISINLabel               matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        QUINESSOMOSLabel                matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Button_5                        matlab.ui.control.Button
        Image7                          matlab.ui.control.Image
        CONTACTOTab                     matlab.ui.container.Tab
        Button_6                        matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UBICACINLabel                   matlab.ui.control.Label
        CONTACTOLabel                   matlab.ui.control.Label
        Image3_3                        matlab.ui.control.Image
    end

   
    properties (Access = private)
        imagen 
        Modelo_Aplicado
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarsesinButton
        function IniciarsesinButtonPushed(app, event)

         usuario = app.UsuarioEditField.Value;
         contrasena = app.ContraseaEditField.Value;

            if ~isempty(usuario) && ~isempty(contrasena)
                % Cambiar a la siguiente pestaña
                app.TabGroup.SelectedTab =app.OPCIONESTab;
                fprintf('Usuario y contraseña completados. Cambiando a la siguiente pestaña.\n');
            else
                % Mostrar un mensaje de error o advertencia
                uialert(app.UIFigure, 'Por favor complete ambos campos', 'Campos incompletos');
            end
           

           % app.TabGroup.SelectedTab=app.OPCIONESTab; % (CAMBIO de PESTAÑA) pasar de INICIO a OPCIONES
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            app.TabGroup.SelectedTab=app.INICIOTab; % OPCIONES --> INICIO
        end

        % Button pushed function: APPdiagnsticoButton
        function APPdiagnsticoButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.DIAGNSTICOTab; % OPCIONES --> DIAGNÓSTICO
        end

        % Button pushed function: InformacinadicionalButton
        function InformacinadicionalButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.INFOTab; % OPCIONES --> INFO
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.OPCIONESTab; % DIAGNOS --> OPCIONES
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TabGroup.SelectedTab=app.OPCIONESTab; % INFO --> OPCIONES
        end

        % Button pushed function: ProductoButton
        function ProductoButtonPushed(app, event)
             app.TabGroup.SelectedTab=app.PRODUCTOTab; %INFO -->PRODUCTO 
        end

        % Button pushed function: QuinessomosButton
        function QuinessomosButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.QUIENESSOMOSTab; %INFO --> QUIENES SOMOS 
        end

        % Button pushed function: ContctanosButton
        function ContctanosButtonPushed(app, event)
             app.TabGroup.SelectedTab=app.CONTACTOTab; %INFO --> CONTACTO
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.TabGroup.SelectedTab=app.INFOTab; %PROD -->INFO
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.TabGroup.SelectedTab=app.INFOTab; %QUIENES SOMOS -->INFO
        end

        % Callback function
        function Button_6Pushed(app, event)
% no sirve 
        end

        % Callback function
        function ButtonPushed(app, event)
% no sirve 
        end

        % Callback function
        function Image3Clicked(app, event)
% no sirve 
        end

        % Button pushed function: Button_6
        function Button_6Pushed2(app, event)
                 app.TabGroup.SelectedTab=app.INFOTab; % CONTACTO -->INFO
        end

        % Value changed function: UsuarioEditField
        function UsuarioEditFieldValueChanged(app, event)
 % no sirve 
        end

        % Value changed function: ContraseaEditField
        function ContraseaEditFieldValueChanged(app, event)
  
        end

        % Value changing function: ContraseaEditField
        function ContraseaEditFieldValueChanging(app, event)
      ChangingValue=event.Value;
            numChars = length(ChangingValue); %longitud del valor que cambia
            maskedValue = repmat('*', 1, numChars); %añadir una fila por x columnas de *
            app.ContraseaEditField.Value = maskedValue; %mostrar asteriscos
        end

        % Button pushed function: SubirImagenButton
        function SubirImagenButtonPushed(app, event)
    %Borrar campos anteriores cuando se suba una imagen
    app.EntropiaEditField_2.Value = 0; 
    app.RangodinmicoEditField_2.Value = 0; 
    app.VarianzaEditField_2.Value = 0;
    app.ECMEditField_2.Value = 0;
    app.Label_8.Text = '';
    app.PSNREditField_2.Value=0;
    app.Lamp.Color = 'white';
    app.Label_9.Text = '';
    imshow(1, 'Parent', app.UIAxes_2);
    app.CDREditField.Value=0;

    % PARA BUSCAR IMAG 
    [archivo, ruta] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'All Image Files'});

    if isequal(archivo, 0)
        return;
    end
    
    ArchivoCompleto = fullfile(ruta, archivo);
    app.imagen = imread(ArchivoCompleto); %la q cargas se guarda en imagen  (arriba)

    imshow(app.imagen, 'Parent', app.UIAxes);
        end

        % Button pushed function: ComprobarcalidadButton
        function ComprobarcalidadButtonPushed(app, event)
modelo = load('Modelo_Calidad.mat', 'mdl_final_TODO_SVM');
modelo_calidad = modelo.mdl_final_TODO_SVM;

if isempty(app.imagen)
    uialert(app.UIFigure, 'Apartado de imagen vacio, suba una imagen', 'No se pudo comprobar la calidad');
    return;
else
I = app.imagen;
entropia2 = entropy(I);
I = double(rgb2gray(I));
rango_dinamico2 = max(I(:)) - min(I(:));
var_intensidad2 = var(I(:)); 
I_filtrada = medfilt2(I);
mse2 = immse(I_filtrada, I);
snr2 = psnr(I_filtrada, I);

Caracteristicas = [entropia2; rango_dinamico2; var_intensidad2; mse2; snr2];

[Modelo_Aplicado, Covariance] = predict(modelo_calidad, Caracteristicas');
Modelo_Aplicado = Modelo_Aplicado{1};
app.Label_8.Text = sprintf('%s', Modelo_Aplicado);
app.Label_8.HorizontalAlignment = 'center';

% caracteristicas
app.EntropiaEditField_2.Value = round(double(entropia2),2);
app.RangodinmicoEditField_2.Value = round(double(rango_dinamico2),2);
app.VarianzaEditField_2.Value = round(double(var_intensidad2),2);
app.ECMEditField_2.Value = round(double(mse2),2);
app.PSNREditField_2.Value = round(double(snr2),2);

% lamp
    if strcmp(app.Label_8.Text, 'Calidad Correcta')
        app.Lamp.Color = 'green'; % Cambia el color del Lamp a verde si la calidad es buena
    else  
        app.Lamp.Color = 'red'; % Cambia el color del Lamp a rojo si la calidad es mala
    end
end
        end

        % Button pushed function: LimpiarcamposButton
        function LimpiarcamposButtonPushed(app, event)

    app.EntropiaEditField_2.Value = 0; 
    app.RangodinmicoEditField_2.Value = 0; 
    app.VarianzaEditField_2.Value = 0;
    app.ECMEditField_2.Value = 0;
    app.Label_8.Text = '';
    app.PSNREditField_2.Value=0;
    app.Lamp.Color = 'white'; 
    app.Label_9.Text = '';
    app.CDREditField.Value=0;
    imshow(1, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: SegmentacinydiagnsticoButton
        function SegmentacinydiagnsticoButtonPushed(app, event)
run ("Segmentacion.mlx");
load('Segmentación.mat', 'funciones');
segm_model = funciones.segm_model;


imagen=app.imagen;

if isempty(app.imagen)
    uialert(app.UIFigure, 'Apartado de imagen vacio, suba una imagen y compruebe su calidad', 'No se pudo realizar la segmentación');
else
if strcmp(app.Label_8.Text, 'Calidad Correcta')
[imagen_con_circulos, copa, disco, roi]= segm_model(imagen);

errorFlag = verificarErroresImagen(copa, disco);

if errorFlag == 0
imshow(imagen_con_circulos, 'Parent', app.UIAxes_2);

T_caracteristicas_DETECCION = funcion_caracteristicas_deteccion(copa, disco, roi);
 modelo_Diagnostico=load ("modelo_final_SVM.mat");
 diag_model=modelo_Diagnostico.mdl_final_SVM;

 app.CDREditField.Value=T_caracteristicas_DETECCION.CDR;

 [Modelo_Diagnostico, Covariance] = predict(diag_model, T_caracteristicas_DETECCION);
Modelo_Diagnostico = Modelo_Diagnostico{1};
app.Label_9.Text = sprintf('%s', Modelo_Diagnostico);
app.Label_9.HorizontalAlignment = 'center';
else 
 uialert(app.UIFigure, 'No se ha realizado el diagnóstico, ya que la segmentación de la imagen puede no ser correcta debido a un fallo, por favor cambie la imagen', 'No se pudo realizar la segmentación');
end
else 
    if strcmp (app.Label_8.Text, '')
        uialert(app.UIFigure, 'Compruebe la calidad de la imagen antes de realizar la segmentación', 'No se pudo realizar la segmentación');
    else
    uialert(app.UIFigure, 'Imagen con mala calidad, por favor suba una nueva imagen', 'No se pudo realizar la segmentación');
    imshow(0, 'Parent', app.UIAxes_2)
    end
    return;
end
end
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            %nada
        end

        % Button pushed function: VisualizarcaractersticasButton
        function VisualizarcaractersticasButtonPushed(app, event)
              app.TabGroup.SelectedTab=app.CARACTERSTICASTab;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.TabGroup.SelectedTab=app.DIAGNSTICOTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.INICIOTab = uitab(app.TabGroup);
            app.INICIOTab.Title = 'INICIO';
            app.Image8 = uiimage(app.INICIOTab);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'inicio.png');
            app.IniciarsesinButton = uibutton(app.INICIOTab, 'push');
            app.IniciarsesinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarsesinButtonPushed, true);
            app.IniciarsesinButton.Text = 'Iniciar sesión';
            app.UsuarioEditFieldLabel = uilabel(app.INICIOTab);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.INICIOTab, 'text');
            app.UsuarioEditField.ValueChangedFcn = createCallbackFcn(app, @UsuarioEditFieldValueChanged, true);
            app.ContraseaEditFieldLabel = uilabel(app.INICIOTab);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.INICIOTab, 'text');
            app.ContraseaEditField.ValueChangedFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanged, true);
            app.ContraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanging, true);
            app.OPCIONESTab = uitab(app.TabGroup);
            app.OPCIONESTab.Title = 'OPCIONES';
            app.Image3 = uiimage(app.OPCIONESTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'opcion.png');
            app.APPdiagnsticoButton = uibutton(app.OPCIONESTab, 'push');
            app.APPdiagnsticoButton.ButtonPushedFcn = createCallbackFcn(app, @APPdiagnsticoButtonPushed, true);
            app.APPdiagnsticoButton.Text = 'APP diagnóstico ';
            app.InformacinadicionalButton = uibutton(app.OPCIONESTab, 'push');
            app.InformacinadicionalButton.ButtonPushedFcn = createCallbackFcn(app, @InformacinadicionalButtonPushed, true);
            app.InformacinadicionalButton.Text = 'Información adicional';
            app.Button = uibutton(app.OPCIONESTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'imagenes_interfaz', 'flecha.png');
            app.Button.Text = '';
            app.Image4 = uiimage(app.OPCIONESTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'logo info.png');
            app.Image5 = uiimage(app.OPCIONESTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'ordenador logo.jpg');
            app.DIAGNSTICOTab = uitab(app.TabGroup);
            app.DIAGNSTICOTab.Title = 'DIAGNÓSTICO';
            app.UIAxes = uiaxes(app.DIAGNSTICOTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.DIAGNSTICOTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.Image12 = uiimage(app.DIAGNSTICOTab);
            app.Image12.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'pie.png');
            app.Button_2 = uibutton(app.DIAGNSTICOTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'imagenes_interfaz', 'flecha.png');
            app.Button_2.Text = '';
            app.ComprobarcalidadButton = uibutton(app.DIAGNSTICOTab, 'push');
            app.ComprobarcalidadButton.ButtonPushedFcn = createCallbackFcn(app, @ComprobarcalidadButtonPushed, true);
            app.ComprobarcalidadButton.Text = 'Comprobar calidad';
            app.Label_8 = uilabel(app.DIAGNSTICOTab);
            app.Label_8.Text = '';
            app.Image11 = uiimage(app.DIAGNSTICOTab);
            app.Image11.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'cabecera.png');
            app.IMAGENDELARETINALabel = uilabel(app.DIAGNSTICOTab);
            app.IMAGENDELARETINALabel.Text = 'IMAGEN DE LA RETINA';
            app.Lamp = uilamp(app.DIAGNSTICOTab);
            app.SubirImagenButton = uibutton(app.DIAGNSTICOTab, 'push');
            app.SubirImagenButton.ButtonPushedFcn = createCallbackFcn(app, @SubirImagenButtonPushed, true);
            app.SubirImagenButton.Text = 'Subir Imagen';
            app.SubirImagenButton.Icon = fullfile(pathToMLAPP, 'imagenes_interfaz', 'carpeta.png');
            app.SegmentacinydiagnsticoButton = uibutton(app.DIAGNSTICOTab, 'push');
            app.SegmentacinydiagnsticoButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentacinydiagnsticoButtonPushed, true);
            app.SegmentacinydiagnsticoButton.Text = 'Segmentación y diagnóstico';
            app.IMAGENDECOPAYDISCOLabel = uilabel(app.DIAGNSTICOTab);
            app.IMAGENDECOPAYDISCOLabel.Text = 'IMAGEN DE COPA Y DISCO';
            app.DIAGNSTICOLabel = uilabel(app.DIAGNSTICOTab);
            app.DIAGNSTICOLabel.Text = 'DIAGNÓSTICO';
            app.Label_9 = uilabel(app.DIAGNSTICOTab);
            app.Label_9.Text = '';
            app.VisualizarcaractersticasButton = uibutton(app.DIAGNSTICOTab, 'push');
            app.VisualizarcaractersticasButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarcaractersticasButtonPushed, true);
            app.VisualizarcaractersticasButton.Text = 'Visualizar características';
            app.CARACTERSTICASTab = uitab(app.TabGroup);
            app.CARACTERSTICASTab.Title = 'CARACTERÍSTICAS';
            app.Image12_2 = uiimage(app.CARACTERSTICASTab);
            app.Image12_2.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'pie.png');
            app.Button_7 = uibutton(app.CARACTERSTICASTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'imagenes_interfaz', 'flecha.png');
            app.Button_7.Text = '';
            app.Image11_2 = uiimage(app.CARACTERSTICASTab);
            app.Image11_2.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'cabecera.png');
            app.CARACTERSTICASLabel_2 = uilabel(app.CARACTERSTICASTab);
            app.CARACTERSTICASLabel_2.Text = 'CARACTERÍSTICAS';
            app.EntropiaEditField_3Label = uilabel(app.CARACTERSTICASTab);
            app.EntropiaEditField_3Label.Text = 'Entropia';
            app.EntropiaEditField_2 = uieditfield(app.CARACTERSTICASTab, 'numeric');
            app.EntropiaEditField_2.Editable = 'off';
            app.RangodinmicoEditField_3Label = uilabel(app.CARACTERSTICASTab);
            app.RangodinmicoEditField_3Label.Text = 'Rango dinámico';
            app.RangodinmicoEditField_2 = uieditfield(app.CARACTERSTICASTab, 'numeric');
            app.RangodinmicoEditField_2.Editable = 'off';
            app.VarianzaEditField_3Label = uilabel(app.CARACTERSTICASTab);
            app.VarianzaEditField_3Label.Text = 'Varianza';
            app.VarianzaEditField_2 = uieditfield(app.CARACTERSTICASTab, 'numeric');
            app.VarianzaEditField_2.Editable = 'off';
            app.ECMEditField_3Label = uilabel(app.CARACTERSTICASTab);
            app.ECMEditField_3Label.Text = 'ECM';
            app.ECMEditField_2 = uieditfield(app.CARACTERSTICASTab, 'numeric');
            app.ECMEditField_2.Editable = 'off';
            app.PSNREditField_3Label = uilabel(app.CARACTERSTICASTab);
            app.PSNREditField_3Label.Text = 'PSNR';
            app.PSNREditField_2 = uieditfield(app.CARACTERSTICASTab, 'numeric');
            app.PSNREditField_2.Editable = 'off';
            app.CALIDADLabel = uilabel(app.CARACTERSTICASTab);
            app.CALIDADLabel.Text = 'CALIDAD';
            app.SEGMENTACINLabel = uilabel(app.CARACTERSTICASTab);
            app.SEGMENTACINLabel.Text = 'SEGMENTACIÓN';
            app.EntropiaEditField_3Label_2 = uilabel(app.CARACTERSTICASTab);
            app.EntropiaEditField_3Label_2.Text = 'CDR';
            app.CDREditField = uieditfield(app.CARACTERSTICASTab, 'numeric');
            app.CDREditField.Editable = 'off';
            app.LimpiarcamposButton = uibutton(app.CARACTERSTICASTab, 'push');
            app.LimpiarcamposButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarcamposButtonPushed, true);
            app.LimpiarcamposButton.Text = 'Limpiar campos';
            app.LimpiarcamposButton.Icon = fullfile(pathToMLAPP, 'imagenes_interfaz', 'basura.png');
            app.INFOTab = uitab(app.TabGroup);
            app.INFOTab.Title = 'INFO';
            app.Image3_2 = uiimage(app.INFOTab);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'opcion.png');
            app.Button_3 = uibutton(app.INFOTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'imagenes_interfaz', 'flecha.png');
            app.Button_3.Text = '';
            app.QuinessomosButton = uibutton(app.INFOTab, 'push');
            app.QuinessomosButton.ButtonPushedFcn = createCallbackFcn(app, @QuinessomosButtonPushed, true);
            app.QuinessomosButton.Text = '¿Quiénes somos?';
            app.ContctanosButton = uibutton(app.INFOTab, 'push');
            app.ContctanosButton.ButtonPushedFcn = createCallbackFcn(app, @ContctanosButtonPushed, true);
            app.ContctanosButton.Text = 'Contáctanos';
            app.ProductoButton = uibutton(app.INFOTab, 'push');
            app.ProductoButton.ButtonPushedFcn = createCallbackFcn(app, @ProductoButtonPushed, true);
            app.ProductoButton.Text = 'Producto';
            app.PRODUCTOTab = uitab(app.TabGroup);
            app.PRODUCTOTab.Title = 'PRODUCTO';
            app.Image9 = uiimage(app.PRODUCTOTab);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'opcion.png');
            app.Button_4 = uibutton(app.PRODUCTOTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'imagenes_interfaz', 'flecha.png');
            app.Button_4.Text = '';
            app.Image10 = uiimage(app.PRODUCTOTab);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'etiketa.png');
            app.Label_7 = uilabel(app.PRODUCTOTab);
            app.Label_7.Text = {'Nuestro software de apoyo al diagnóstico de glaucoma utiliza algoritmos avanzados para analizar'; 'imágenes del nervio óptico y campos visuales, ayudando a los profesionales de la salud a detectar'; 'signos tempranos de glaucoma y monitorear su progresión.'};
            app.SoftwaredeApoyoalDiagnsticodeGlaucomaLabel = uilabel(app.PRODUCTOTab);
            app.SoftwaredeApoyoalDiagnsticodeGlaucomaLabel.Text = 'Software de Apoyo al Diagnóstico de Glaucoma';
            app.QUIENESSOMOSTab = uitab(app.TabGroup);
            app.QUIENESSOMOSTab.Title = 'QUIENES SOMOS';
            app.Image7 = uiimage(app.QUIENESSOMOSTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'opcion.png');
            app.Button_5 = uibutton(app.QUIENESSOMOSTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'imagenes_interfaz', 'flecha.png');
            app.Button_5.Text = '';
            app.Label_5 = uilabel(app.QUIENESSOMOSTab);
            app.Label_5.Text = {''; ''; 'EyeHealth Diagnostics es una empresa innovadora dedicada a desarrollar soluciones avanzadas para'; 'el diagnóstico temprano y efectivo del glaucoma. Fundada en 2024, EyeHealth Diagnostics nació con la'; 'misión de mejorar la calidad de vida de las personas mediante la detección temprana del glaucoma.'; 'Inspirados por la necesidad crítica de diagnósticos precisos y accesibles, hemos crecido para convertirnos'; 'en líderes en el campo de la salud ocular.'};
            app.QUINESSOMOSLabel = uilabel(app.QUIENESSOMOSTab);
            app.QUINESSOMOSLabel.Text = {'¿QUIÉNES SOMOS?'; ''};
            app.Label_6 = uilabel(app.QUIENESSOMOSTab);
            app.Label_6.Text = {'Nuestra misión es proporcionar tecnología de diagnóstico de glaucoma precisa y accesible, ayudando'; ' a los profesionales de la salud a detectar y tratar el glaucoma de manera más eficaz. Nos esforzamos'; 'por ser el referente mundial en soluciones de diagnóstico ocular, mejorando la salud visual y previniendo'; 'la ceguera causada por el glaucoma.'};
            app.MISIONYVISINLabel = uilabel(app.QUIENESSOMOSTab);
            app.MISIONYVISINLabel.Text = 'MISION Y VISIÓN';
            app.VALORESLabel = uilabel(app.QUIENESSOMOSTab);
            app.VALORESLabel.Text = 'VALORES';
            app.InnovacincalidadaccesibilidadycompromisoconlasaludLabel = uilabel(app.QUIENESSOMOSTab);
            app.InnovacincalidadaccesibilidadycompromisoconlasaludLabel.Text = 'Innovación, calidad, accesibilidad y compromiso con la salud.';
            app.CONTACTOTab = uitab(app.TabGroup);
            app.CONTACTOTab.Title = 'CONTACTO';
            app.Image3_3 = uiimage(app.CONTACTOTab);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'opcion.png');
            app.CONTACTOLabel = uilabel(app.CONTACTOTab);
            app.CONTACTOLabel.Text = 'CONTACTO';
            app.UBICACINLabel = uilabel(app.CONTACTOTab);
            app.UBICACINLabel.Text = 'UBICACIÓN';
            app.Label = uilabel(app.CONTACTOTab);
            app.Label.Text = {''; 'Faculty of Engineering'; ''; 'Escuela Politécnica Superior, Loramendi Kalea, '; '4, 20500 Arrasate, Gipuzkoa'};
            app.Label_2 = uilabel(app.CONTACTOTab);
            app.Label_2.Text = {'Nombre'; ''; 'Descripción'; ''};
            app.Image6 = uiimage(app.CONTACTOTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'imagenes_interfaz', 'mapa.png');
            app.Label_3 = uilabel(app.CONTACTOTab);
            app.Label_3.Text = {'Teléfono'; ''; 'Mail'};
            app.Label_4 = uilabel(app.CONTACTOTab);
            app.Label_4.Text = {'+34 648951789'; ''; 'info@eyehealthdiagnostics.com'};
            app.Button_6 = uibutton(app.CONTACTOTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed2, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'imagenes_interfaz', 'flecha.png');
            app.Button_6.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end